classdef deneme1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AddCityEditField      matlab.ui.control.EditField
        AddCityLabel          matlab.ui.control.Label
        GetInformationButton  matlab.ui.control.Button
        SelectCityDropDown    matlab.ui.control.DropDown
        SelectCityLabel       matlab.ui.control.Label
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        apiKey = '0de2ef6646e30c5a61a344c227981893';
        cities = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetInformationButton
        function GetInformationButtonPushed(app, event)
            selectedCity = app.CityListBox.Value;
            if isempty(selectedCity)
                return;
            end
            
            % Şehir adını koordinatlara dönüştürme (örnek bir yer tutucu fonksiyon kullanarak)
            [lat, lon] = geocodeCity(selectedCity);
            
            % Hava durumu verilerini alma
            forecastData = fetchFiveDayForecast(lat, lon, app.apiKey);
            % Grafiklerin güncellenmesi
            plot(app.TemperatureAxes, forecastData.timestamps, forecastData.temperature, '-o');
            title(app.TemperatureAxes, 'Temperature (°C)');
            xlabel(app.TemperatureAxes, 'Date');
            ylabel(app.TemperatureAxes, 'Temperature');

            plot(app.HumidityAxes, forecastData.timestamps, forecastData.humidity, '-o');
            title(app.HumidityAxes, 'Humidity (%)');
            xlabel(app.HumidityAxes, 'Date');
            ylabel(app.HumidityAxes, 'Humidity');

            plot(app.WindSpeedAxes, forecastData.timestamps, forecastData.wind_speed, '-o');
            title(app.WindSpeedAxes, 'Wind Speed (m/s)');
            xlabel(app.WindSpeedAxes, 'Date');
            ylabel(app.WindSpeedAxes, 'Wind Speed');
 
        end

        % Callback function: not associated with a component
        function EditFieldValueChanged(app, event)
            %value = app.AddCityEditField.Value; %use tihs 'value' instead of 'city'
            value = app.CityEditField.Value;
            if ~ismember(value, app.cities)
                app.cities{end+1} = value;
                app.CityListBox.Items = app.cities;
            end 
        end

        % Value changed function: AddCityEditField
        function AddCityEditFieldValueChanged2(app, event)
            value = app.AddCityEditField.Value;
            % city = app.CityEditField.Value;
            if ~ismember(value, app.cities)
                app.cities{end+1} = value;
                app.CityListBox.Items = app.cities;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Wind Speed Forecast for Ankara ')
            xlabel(app.UIAxes3, 'Date')
            ylabel(app.UIAxes3, 'Wind Speed (m/s)')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Temperature Forecast for Ankara')
            xlabel(app.UIAxes4, 'Date')
            ylabel(app.UIAxes4, 'Temperature (°C)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Humidity Forecast for Ankara')
            xlabel(app.UIAxes5, 'Date')
            ylabel(app.UIAxes5, 'Humidity (%)')
            zlabel(app.UIAxes5, 'Z')
            app.SelectCityLabel = uilabel(app.UIFigure);
            app.SelectCityLabel.Text = 'Select City:';
            app.SelectCityDropDown = uidropdown(app.UIFigure);
            app.SelectCityDropDown.Items = {'Adana'};
            app.SelectCityDropDown.Value = 'Adana';
            app.GetInformationButton = uibutton(app.UIFigure, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @GetInformationButtonPushed, true);
            app.GetInformationButton.Text = 'Get Information';
            app.AddCityLabel = uilabel(app.UIFigure);
            app.AddCityLabel.Text = 'Add City';
            app.AddCityEditField = uieditfield(app.UIFigure, 'text');
            app.AddCityEditField.ValueChangedFcn = createCallbackFcn(app, @AddCityEditFieldValueChanged2, true);
            app.AddCityEditField.Value = 'Antalya';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deneme1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end